classdef geostatsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadButton                     matlab.ui.control.Button
        XcelldimmcellEditFieldLabel    matlab.ui.control.Label
        XcelldimmcellEditField         matlab.ui.control.NumericEditField
        YcelldimmcallEditFieldLabel    matlab.ui.control.Label
        YcelldimmcallEditField         matlab.ui.control.NumericEditField
        ptsXEditFieldLabel             matlab.ui.control.Label
        ptsXEditField                  matlab.ui.control.NumericEditField
        ptsYEditFieldLabel             matlab.ui.control.Label
        ptsYEditField                  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
        VariogramButton                matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        ClearPlotsButton               matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        YScaleFactorEditFieldLabel     matlab.ui.control.Label
        YScaleFactorEditField          matlab.ui.control.NumericEditField
        EntrogramButton                matlab.ui.control.Button
        DSpatialGeostatisticsToolsLabel  matlab.ui.control.Label
        MustbematfilecontainingLabel   matlab.ui.control.Label
        DmatrixtobeanalyzednamedfieldLabel  matlab.ui.control.Label
        VisualizethefieldtobeanalyzedLabel  matlab.ui.control.Label
        SetparametersforthevariogramcalculationLabel  matlab.ui.control.Label
        ComputeandplotrawvariogramLabel  matlab.ui.control.Label
        IftherearemorepointsinYdirectionthaninXLabel  matlab.ui.control.Label
        thisscalingfactorexpandsthewindowbymoreLabel  matlab.ui.control.Label
        cellsinYforevery1cellexpansioninXLabel  matlab.ui.control.Label
        DistancesinXOnlyCheckBox       matlab.ui.control.CheckBox
        DistancesinYOnlyCheckBox       matlab.ui.control.CheckBox
        XScaleFactorEditFieldLabel     matlab.ui.control.Label
        XScaleFactorEditField          matlab.ui.control.NumericEditField
        SameideabutinXdirectionLabel   matlab.ui.control.Label
        IncreasewindowinXonlyCheckBox  matlab.ui.control.CheckBox
        IncreasewindowinYonlyCheckBox  matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,pathname] = uigetfile;
            loaddata = fullfile(pathname,filename);
            data = load(loaddata);
            
            setappdata(0,'data',data);

        end

        % Value changed function: XcelldimmcellEditField
        function XcelldimmcellEditFieldValueChanged(app, event)
            value = app.XcelldimmcellEditField.Value;
            setappdata(0,'xdim',value);
        end

        % Value changed function: YcelldimmcallEditField
        function YcelldimmcallEditFieldValueChanged(app, event)
            value = app.YcelldimmcallEditField.Value;
            setappdata(0,'ydim',value);
        end

        % Value changed function: ptsXEditField
        function ptsXEditFieldValueChanged(app, event)
            value = app.ptsXEditField.Value;
            setappdata(0,'numptsx',value);
        end

        % Value changed function: ptsYEditField
        function ptsYEditFieldValueChanged(app, event)
            value = app.ptsYEditField.Value;
            setappdata(0,'numptsy',value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            data = getappdata(0,'data');
            field = data.field;
            surf(app.UIAxes,field,'EdgeColor','none');
            view(app.UIAxes,[0 90])
        end

        % Callback function
        function BinSizeEditFieldValueChanged(app, event)
            value = app.BinSizeEditField.Value;
            setappdata(0,'binsize',value);
        end

        % Button pushed function: VariogramButton
        function VariogramButtonPushed(app, event)
            data = getappdata(0,'data');
            field = data.field;
            xdim = getappdata(0,'xdim');
            ydim = getappdata(0,'ydim');
            xpts = getappdata(0,'numptsx');
            ypts = getappdata(0,'numptsy');
            vflag = getappdata(0,'vflag');
            hflag = getappdata(0,'hflag');

            cd src
            [distance, gamma] = variogram_comp(field, xdim, ydim, xpts, ypts, vflag, hflag);
            cd ..
            
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,distance,gamma);
            xlim(app.UIAxes2,[0 round(0.67*max(distance))]);
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.UIAxes2 = uiputfile('variogram.fig');
        end

        % Value changed function: YScaleFactorEditField
        function YScaleFactorEditFieldValueChanged(app, event)
            value = app.YScaleFactorEditField.Value;
            setappdata(0,'yscale',value);
        end

        % Button pushed function: EntrogramButton
        function EntrogramButtonPushed(app, event)
            data = getappdata(0,'data');
            field = data.field;
            xdim = getappdata(0,'xdim');
            ydim = getappdata(0,'ydim');
            xscale = getappdata(0,'xscale');
            yscale = getappdata(0,'yscale');
            xflag = getappdata(0,'xflag');
            yflag = getappdata(0,'yflag');
            
            cd src
            [HR, windim, xplotline, yplotline] = entrogram_comp(field, xdim, ydim, xscale, yscale, xflag, yflag);
            cd ..
            
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,windim,HR);
            plot(app.UIAxes3,xplotline,yplotline,'--','color',[0.5 0.5 0.5 0.5]);
            xlim(app.UIAxes3,[0 max(windim)]);
        end

        % Value changed function: DistancesinXOnlyCheckBox
        function DistancesinXOnlyCheckBoxValueChanged(app, event)
            value = app.DistancesinXOnlyCheckBox.Value;
            if value == 1
                setappdata(0,'hflag',value);
            else
                setappdata(0,'hflag',value);
            end
        end

        % Value changed function: DistancesinYOnlyCheckBox
        function DistancesinYOnlyCheckBoxValueChanged(app, event)
            value = app.DistancesinYOnlyCheckBox.Value;
            if value == 1
                setappdata(0,'vflag',value);
            else
                setappdata(0,'vflag',value);
            end
        end

        % Value changed function: XScaleFactorEditField
        function XScaleFactorEditFieldValueChanged(app, event)
            value = app.XScaleFactorEditField.Value;
            setappdata(0,'xscale',value);
        end

        % Value changed function: IncreasewindowinXonlyCheckBox
        function IncreasewindowinXonlyCheckBoxValueChanged(app, event)
            value = app.IncreasewindowinXonlyCheckBox.Value;
            if value == 1
                setappdata(0,'yflag',value);
            else
                setappdata(0,'yflag',value);
            end
        end

        % Value changed function: IncreasewindowinYonlyCheckBox
        function IncreasewindowinYonlyCheckBoxValueChanged(app, event)
            value = app.IncreasewindowinYonlyCheckBox.Value;
            if value == 1
                setappdata(0,'xflag',value);
            else
                setappdata(0,'xflag',value);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.XcelldimmcellEditFieldLabel = uilabel(app.UIFigure);
            app.XcelldimmcellEditFieldLabel.Text = 'X cell dim. (m/cell)';
            app.XcelldimmcellEditField = uieditfield(app.UIFigure, 'numeric');
            app.XcelldimmcellEditField.ValueChangedFcn = createCallbackFcn(app, @XcelldimmcellEditFieldValueChanged, true);
            app.YcelldimmcallEditFieldLabel = uilabel(app.UIFigure);
            app.YcelldimmcallEditFieldLabel.Text = 'Y cell dim. (m/call)';
            app.YcelldimmcallEditField = uieditfield(app.UIFigure, 'numeric');
            app.YcelldimmcallEditField.ValueChangedFcn = createCallbackFcn(app, @YcelldimmcallEditFieldValueChanged, true);
            app.ptsXEditFieldLabel = uilabel(app.UIFigure);
            app.ptsXEditFieldLabel.Text = '# pts. X';
            app.ptsXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ptsXEditField.ValueChangedFcn = createCallbackFcn(app, @ptsXEditFieldValueChanged, true);
            app.ptsYEditFieldLabel = uilabel(app.UIFigure);
            app.ptsYEditFieldLabel.Text = '# pts. Y';
            app.ptsYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ptsYEditField.ValueChangedFcn = createCallbackFcn(app, @ptsYEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Field')
            xlabel(app.UIAxes, 'X (# cells)')
            ylabel(app.UIAxes, 'Y (# cells)')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.VariogramButton = uibutton(app.UIFigure, 'push');
            app.VariogramButton.ButtonPushedFcn = createCallbackFcn(app, @VariogramButtonPushed, true);
            app.VariogramButton.Text = 'Variogram';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Raw Variogram')
            xlabel(app.UIAxes2, 'Distance (m)')
            ylabel(app.UIAxes2, 'Gamma')
            app.ClearPlotsButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Entrogram')
            xlabel(app.UIAxes3, 'Distance (m)')
            ylabel(app.UIAxes3, 'H_R')
            app.YScaleFactorEditFieldLabel = uilabel(app.UIFigure);
            app.YScaleFactorEditFieldLabel.Text = 'Y-Scale Factor';
            app.YScaleFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.YScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @YScaleFactorEditFieldValueChanged, true);
            app.EntrogramButton = uibutton(app.UIFigure, 'push');
            app.EntrogramButton.ButtonPushedFcn = createCallbackFcn(app, @EntrogramButtonPushed, true);
            app.EntrogramButton.Text = 'Entrogram';
            app.DSpatialGeostatisticsToolsLabel = uilabel(app.UIFigure);
            app.DSpatialGeostatisticsToolsLabel.Text = '2-D Spatial Geostatistics Tools';
            app.MustbematfilecontainingLabel = uilabel(app.UIFigure);
            app.MustbematfilecontainingLabel.Text = 'Must be .mat file containing';
            app.DmatrixtobeanalyzednamedfieldLabel = uilabel(app.UIFigure);
            app.DmatrixtobeanalyzednamedfieldLabel.Text = '2-D matrix to be analyzed named ''field''';
            app.VisualizethefieldtobeanalyzedLabel = uilabel(app.UIFigure);
            app.VisualizethefieldtobeanalyzedLabel.Text = 'Visualize the field to be analyzed';
            app.SetparametersforthevariogramcalculationLabel = uilabel(app.UIFigure);
            app.SetparametersforthevariogramcalculationLabel.Text = 'Set parameters for the variogram calculation';
            app.ComputeandplotrawvariogramLabel = uilabel(app.UIFigure);
            app.ComputeandplotrawvariogramLabel.Text = 'Compute and plot raw variogram';
            app.IftherearemorepointsinYdirectionthaninXLabel = uilabel(app.UIFigure);
            app.IftherearemorepointsinYdirectionthaninXLabel.Text = 'If there are more points in Y-direction than in X';
            app.thisscalingfactorexpandsthewindowbymoreLabel = uilabel(app.UIFigure);
            app.thisscalingfactorexpandsthewindowbymoreLabel.Text = 'this scaling factor expands the window by more';
            app.cellsinYforevery1cellexpansioninXLabel = uilabel(app.UIFigure);
            app.cellsinYforevery1cellexpansioninXLabel.Text = 'cells in Y for every 1 cell expansion in X';
            app.DistancesinXOnlyCheckBox = uicheckbox(app.UIFigure);
            app.DistancesinXOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistancesinXOnlyCheckBoxValueChanged, true);
            app.DistancesinXOnlyCheckBox.Text = 'Distances in X Only';
            app.DistancesinYOnlyCheckBox = uicheckbox(app.UIFigure);
            app.DistancesinYOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistancesinYOnlyCheckBoxValueChanged, true);
            app.DistancesinYOnlyCheckBox.Text = 'Distances in Y Only';
            app.XScaleFactorEditFieldLabel = uilabel(app.UIFigure);
            app.XScaleFactorEditFieldLabel.Text = 'X-Scale Factor';
            app.XScaleFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.XScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @XScaleFactorEditFieldValueChanged, true);
            app.SameideabutinXdirectionLabel = uilabel(app.UIFigure);
            app.SameideabutinXdirectionLabel.Text = 'Same idea but in X-direction';
            app.IncreasewindowinXonlyCheckBox = uicheckbox(app.UIFigure);
            app.IncreasewindowinXonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncreasewindowinXonlyCheckBoxValueChanged, true);
            app.IncreasewindowinXonlyCheckBox.Text = 'Increase window in X only';
            app.IncreasewindowinYonlyCheckBox = uicheckbox(app.UIFigure);
            app.IncreasewindowinYonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncreasewindowinYonlyCheckBoxValueChanged, true);
            app.IncreasewindowinYonlyCheckBox.Text = 'Increase window in Y only';
        end
    end

    methods (Access = public)

        % Construct app
        function app = geostatsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end